classdef lightwave_8163a_query < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        LaserStatusLamp             matlab.ui.control.Lamp
        LaserStatusLampLabel        matlab.ui.control.Label
        LaserToggleButton           matlab.ui.control.Button
        LightSrcLabel               matlab.ui.control.Label
        ExportDataButton            matlab.ui.control.Button
        AutoAdjustYLimButton        matlab.ui.control.Button
        YLimEditField               matlab.ui.control.EditField
        YLimEditField_2Label        matlab.ui.control.Label
        UnitsKnob                   matlab.ui.control.DiscreteKnob
        UnitsKnobLabel              matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        ParametersLabel             matlab.ui.control.Label
        MeasurementLabel            matlab.ui.control.Label
        GPIBLabel                   matlab.ui.control.Label
        QueryStatusLamp             matlab.ui.control.Lamp
        QueryStatusLampLabel        matlab.ui.control.Label
        ToggleReadButton            matlab.ui.control.Button
        GPIBCxnLamp                 matlab.ui.control.Lamp
        GPIBCxnLampLabel            matlab.ui.control.Label
        AvgWindowEditField          matlab.ui.control.NumericEditField
        AvgWindowEditFieldLabel     matlab.ui.control.Label
        ChangeAvgWindowButton       matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        GoalEditField               matlab.ui.control.NumericEditField
        GoalEditFieldLabel          matlab.ui.control.Label
        TimeElapsedmEditField       matlab.ui.control.NumericEditField
        TimeElapsedmEditFieldLabel  matlab.ui.control.Label
        CurrentetaEditField         matlab.ui.control.NumericEditField
        CurrentetaEditFieldLabel    matlab.ui.control.Label
        MaxPowerEditField           matlab.ui.control.NumericEditField
        MaxPowerEditFieldLabel      matlab.ui.control.Label
        YLimitsSlider               matlab.ui.control.RangeSlider
        YLimitsSliderLabel          matlab.ui.control.Label
        CurrentValueEditField       matlab.ui.control.NumericEditField
        CurrentValueEditFieldLabel  matlab.ui.control.Label
        StatisticsLabel             matlab.ui.control.Label
        MinEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        MaxEditField                matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        StdDevpWEditField           matlab.ui.control.NumericEditField
        StdDevLabel                 matlab.ui.control.Label
        MeanEditField               matlab.ui.control.NumericEditField
        MeanEditFieldLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % define objects / variables
            s % serial port handler
            port % port name string
            visagpib % visa object
            baud 
            avgWindow
            data_size
            t
            p
            connected
            default_address
            power_unit
            halt_read
            F % future handle
            Q % data queue
            tmr % timer to schedule read callback
            h % animated line
            gauge_flag
            ylim_flag
            export_flag
            read_time
            hline
    end
    
    methods (Access = private)
        
        function scale = get_powu_conv(app)
            units = ["mW","uW","nW","pW"];
            units_scale = [1e3,1e6,1e9,1e12];
            for i=1:length(units)
                if(strcmp(units(i),app.power_unit))
                    scale = units_scale(i);
                    break;
                end
            end
        end

        
        function r = read(app,hObject,varargin)

            % retrieve plotting object
            % disp("1");
            % hObject.UIAxes.Children
            line = hObject.UIAxes.Children(1);
            line.LineWidth=2.5;
            % disp("2");
            % app.hline

            % read  fxnality
            writeline(app.visagpib,"fetc2:pow?");
            p = double(str2num(readline(app.visagpib)));
            
            % scale power unit
            p = p*app.get_powu_conv();
            
            % add to display
            app.CurrentValueEditField.Value = p;
            
            if(length(line.XData) < app.data_size)
                
                line.XData = [line.XData line.XData(length(line.XData))+1];
                line.YData = [line.YData p];
                                % length(line.XData)
            else % wrap, circular, calc stats
                % length(line.XData)
                %line.XData = [line.XData(2,:) line.XData(app.data_size)+1];
                line.YData = [line.YData(1,2:end) p];
            end

                % TODO plot fit @ some point

                % display stats
                app.MeanEditField.Value = mean(line.YData);
                % app.StdDevpWEditField.Value = round(std(line.YData)/app.MeanEditField.Value*1e2,2);
                app.StdDevpWEditField.Value = round(std(line.YData),5);
                app.MinEditField.Value = min(line.YData);
                app.MaxEditField.Value = max(line.YData);

                % update plot limits
                % hObject.UIAxes.YLim = [app.MinEditField.Value app.MaxEditField.Value];

                % update power gauge
                % try
                % if(app.gauge_flag) % update limits
                %     avg = app.MeanEditField.Value;
                %     if((avg - app.GaugeWindowSizeKnob.Value)<=0)
                %         app.PowerCurrentAveragingWindowGauge.Limits = [0 floor(avg+app.GaugeWindowSizeKnob.Value)];
                %     else
                %         app.PowerCurrentAveragingWindowGauge.Limits = [floor(avg - app.GaugeWindowSizeKnob.Value) floor(avg+app.GaugeWindowSizeKnob.Value)];
                %     end
                %     app.gauge_flag = false;
                % end
                % catch e
                % end

                % update ylim
                try
                hObject.UIAxes.YLim = app.YLimitsSlider.Value;
                end
                % if(app.ylim_flag)
                %     app.ylim_flag = false;
                %     disp(app.YLimitsSlider.Value)
                %     hObject.UIAxes.YLim = app.YLimitsSlider.Value;
                % end
                % app.PowerCurrentAveragingWindowGauge.Value = app.MeanEditField.Value;

                % efficiency
                app.CurrentetaEditField.Value = round(1e2*(p/app.MaxPowerEditField.Value),2)

                % export data
                if(app.export_flag)
                    if(length(line.XData) < length(line.YData))
                        disp('skip');
                    else
                        % record elapsed time
                        app.read_time = toc(app.read_time);
                        app.TimeElapsedmEditField.Value = app.read_time/60;

                        %  export (overwrite by default)
                        % add header
                        writedata = cell(length(line.YData)+1,2);
                        writedata{1,1} = 'Elapsed Time (s)';
                        writedata{1,2} = app.read_time/60;
                        % size(mat2cell(line.XData',ones(size(line.XData))))
                        writedata(2:end,1) = num2cell(line.XData');
                        writedata(2:end,2) = num2cell(line.YData');
                        writecell(writedata,'./data-series.xlsx');
                        app.export_flag=false;
                    end
                    
                
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.h = animatedline;
            app.default_address = "30"; % for GPIB cxn
            app.connected = false;
            app.baud = 9600; % default
            app.data_size = app.AvgWindowEditField.Value; % samples
            app.t = zeros(1,app.data_size); % time
            app.p = zeros(1,app.data_size); % power
            app.power_unit = app.UnitsKnob.Value;
            app.gauge_flag = false;
            app.ylim_flag = false;
            app.UIAxes.YLabel.String = app.UnitsKnob.Value;
            app.export_flag=false;
            app.read_time = 0;
            app.UIFigure.WindowState = 'maximized'; 

            app.hline = yline(app.UIAxes,0,'--','color','r','LineWidth',1);

            % initalization
            value = app.YLimitsSlider.Value;
            app.YLimEditField.Value = strcat("[",num2str(value(1)),",",num2str(value(2)),"]");
            

            % parallel 
            % app.halt_read = true
            % parpool;
            % app.Q = parallel.pool.DataQueue;
            % afterEach(Q,@(read) updatePlot(app));
            % see https://www.mathworks.com/help/parallel-computing/parallel.pool.parfeval.html#bt2u0zs-7
            % see https://www.mathworks.com/help/matlab/ref/parallel.future.aftereach.html?s_tid=doc_ta
            app.tmr = timer();
            app.tmr.period = 155e-3; % millis (can't be too small or else will block)
            app.tmr.ExecutionMode = 'fixedRate';
            app.tmr.TimerFcn = @(~,~)app.read(app,app.UIAxes); % assign callback
            % handles.timer = app.tmr % so i can stop later

            % try
                app.visagpib = visadev(strcat("GPIB0::",app.default_address,"::INSTR"));
                app.connected = true;
                disp("Connected");
                app.GPIBCxnLamp.Color = 'green';
            % catch e
            %     ME = MException('', ...
            %                 'Error connecting to device');
            %     throw(ME);
            %     % disp(e);
            %     app.GPIBCxnLamp.Color = 'green';
            % end

            % add one point to axes
            plot(app.UIAxes,0,0);

            % update laser status
            writeline(app.visagpib,"outp1:stat?");
            state=int32(str2double(readline(app.visagpib)));
            if(state) % laser ON
                app.LaserStatusLamp.Color='g'; % update status lamp
            else % laser OFF
                app.LaserStatusLamp.Color='r'; % update status lamp

            end


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % attempt GPIB cxn again 
            % TODO
            % app.halt_read = false
            % disp("starting thread");
            % app.F = parfeval(@app.read,0,app);
            stop(app.tmr);
            app.GPIBCxnLamp.Color = 'red';
            try
                app.visagpib = visadev(strcat("GPIB0::",app.default_address,"::INSTR"));
                app.connected = true;
                disp("Connected");
                app.GPIBCxnLamp.Color = 'green';
            catch e
                disp("Error connecting to device");
                disp(e);
                app.GPIBCxnLamp.Color = 'green';
            end
        end

        % Button pushed function: ToggleReadButton
        function ToggleReadButtonPushed(app, event)

            red = sum(app.QueryStatusLamp.Color == [1 0 0]) == 3;
            if(red)
                if(app.connected)
    
                    % flush queue
                    % result = readline(app.visagpib);

                    % set to continuous
                    writeline(app.visagpib,"init2:cont1");
                    writeline(app.visagpib,"init2:cont?");
                    result = readline(app.visagpib);
                    if(~str2num(result))
                        ME = MException('', ...
                            '8163A not in continous mode, set failed');
                        throw(ME);
                    end
    
                end
                % start timer
                start(app.tmr)
                disp("start timer");
                app.QueryStatusLamp.Color = 'g';
                app.read_time = tic;
            else
                % stop timer
                stop(app.tmr);
                disp("stop timer");
                app.QueryStatusLamp.Color = 'r';

            end

        end

        % Button down function: LeftPanel
        function LeftPanelButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.tmr);
            delete(app.tmr)
            delete(app)

        end

        % Value changed function: UnitsKnob
        function UnitsKnobValueChanged(app, event)
            app.power_unit = app.UnitsKnob.Value;
            switch(app.power_unit)
                case 'pW'
                    app.UIAxes.YLabel.String = "Power (pW)";
                case 'nW'
                    app.UIAxes.YLabel.String = "Power (nW)";
                case 'uW'
                    app.UIAxes.YLabel.String = "Power (uW)";
                case 'mW'
                    app.UIAxes.YLabel.String = "Power (mW)";
            end
            
        end

        % Button pushed function: ChangeAvgWindowButton
        function ChangeAvgWindowButtonPushed(app, event)
            app.data_size = round(app.AvgWindowEditField.Value);
        end

        % Callback function
        function GaugeWindowSizeKnobValueChanged(app, event)
            % value = app.GaugeWindowSizeKnob.Value;
            app.gauge_flag = true;
        end

        % Value changed function: YLimitsSlider
        function YLimitsSliderValueChanged(app, event)
            % app.ylim_flag = true;
            value = app.YLimitsSlider.Value;
            app.YLimEditField.Value = strcat("[",num2str(value(1)),",",num2str(value(2)),"]");
            app.UIAxes.YLim = [value(1), value(2)];
            
        end

        % Button pushed function: AutoAdjustYLimButton
        function AutoAdjustYLimButtonPushed(app, event)
            adjust = app.MeanEditField.Value / 10;
            adj_m = app.MinEditField.Value-adjust;
            adj_p = app.MinEditField.Value + adjust;
            if(adj_m<0)
                adj_m = 0;
            end
            if(adj_p > app.YLimitsSlider.Limits(2))
                adj_p = app.YLimitsSlider.Limits(2);
            end
            try
            app.YLimitsSlider.Value= [adj_m app.MaxEditField.Value+adjust];
            end
            value = app.YLimitsSlider.Value;
            app.YLimEditField.Value = strcat("[",num2str(value(1)),",",num2str(value(2)),"]");

            % app.YLimitsSlider.Value;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.export_flag = true;
            
        end

        % Value changed function: GoalEditField
        function GoalEditFieldValueChanged(app, event)
            value = app.GoalEditField.Value;
            % app.get_powu_conv()
            % disp(value)
            app.hline.Value =value;%/app.get_powu_conv();
             % stop(app.tmr);
            % app.hline
            % yline(app.hline, str2double(value), '-','color','r', 'LineWidth', 3);

        end

        % Button pushed function: LaserToggleButton
        function LaserToggleButtonPushed(app, event)

            writeline(app.visagpib,"outp1:stat?");
            state=int32(str2double(readline(app.visagpib))); disp(state)
            if(state) 
                % laser on, turn off
                disp('turn off laser');
                writeline(app.visagpib,"outp1:stat 0")% turn off
                app.LaserStatusLamp.Color='r'; % update status lamp
            else
                % laser off, turn on
                disp('turn on laser');
                writeline(app.visagpib,"outp1:stat 1"); % turn on
                app.LaserStatusLamp.Color='g'; % update status lamp
                pause(.2);
            end

            % check state
            writeline(app.visagpib,"outp1:stat?");
            check_state=int32(str2double(readline(app.visagpib)));
            disp(state);
            disp(check_state);
            if(state == check_state)
                % raise error
                ME = MException('', ...
                    '81689A status not changed, check laser');
                throw(ME);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {675, 675};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.ChangeAvgWindowButton = uibutton(app.LeftPanel, 'push');
            app.ChangeAvgWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeAvgWindowButtonPushed, true);
            app.ChangeAvgWindowButton.Text = 'Change Avg. Window';
            app.AvgWindowEditFieldLabel = uilabel(app.LeftPanel);
            app.AvgWindowEditFieldLabel.Text = 'Avg. Window';
            app.AvgWindowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AvgWindowEditField.Value = 300;
            app.GPIBCxnLampLabel = uilabel(app.LeftPanel);
            app.GPIBCxnLampLabel.Text = 'GPIB Cxn.';
            app.GPIBCxnLamp = uilamp(app.LeftPanel);
            app.ToggleReadButton = uibutton(app.LeftPanel, 'push');
            app.ToggleReadButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleReadButtonPushed, true);
            app.ToggleReadButton.Text = 'Toggle Read';
            app.QueryStatusLampLabel = uilabel(app.LeftPanel);
            app.QueryStatusLampLabel.Text = 'Query Status';
            app.QueryStatusLamp = uilamp(app.LeftPanel);
            app.GPIBLabel = uilabel(app.LeftPanel);
            app.GPIBLabel.Text = 'GPIB';
            app.MeasurementLabel = uilabel(app.LeftPanel);
            app.MeasurementLabel.Text = 'Measurement';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.UnitsKnobLabel = uilabel(app.LeftPanel);
            app.UnitsKnobLabel.Text = 'Units';
            app.UnitsKnob = uiknob(app.LeftPanel, 'discrete');
            app.UnitsKnob.Items = {'pW', 'nW', 'uW', 'mW'};
            app.UnitsKnob.ValueChangedFcn = createCallbackFcn(app, @UnitsKnobValueChanged, true);
            app.UnitsKnob.Value = 'pW';
            app.YLimEditField_2Label = uilabel(app.LeftPanel);
            app.YLimEditField_2Label.Text = 'YLim';
            app.YLimEditField = uieditfield(app.LeftPanel, 'text');
            app.YLimEditField.Editable = 'off';
            app.AutoAdjustYLimButton = uibutton(app.LeftPanel, 'push');
            app.AutoAdjustYLimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAdjustYLimButtonPushed, true);
            app.AutoAdjustYLimButton.Text = 'Auto Adjust YLim';
            app.ExportDataButton = uibutton(app.LeftPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.LightSrcLabel = uilabel(app.LeftPanel);
            app.LightSrcLabel.Text = 'Light Src.';
            app.LaserToggleButton = uibutton(app.LeftPanel, 'push');
            app.LaserToggleButton.ButtonPushedFcn = createCallbackFcn(app, @LaserToggleButtonPushed, true);
            app.LaserToggleButton.Text = 'Laser Toggle';
            app.LaserStatusLampLabel = uilabel(app.LeftPanel);
            app.LaserStatusLampLabel.Text = 'Laser Status';
            app.LaserStatusLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Power')
            ylabel(app.UIAxes, 'Power (nW)')
            zlabel(app.UIAxes, 'Z')
            app.MeanEditFieldLabel = uilabel(app.RightPanel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.RightPanel, 'numeric');
            app.MeanEditField.Editable = 'off';
            app.StdDevLabel = uilabel(app.RightPanel);
            app.StdDevLabel.Text = 'Std. Dev. (pW)';
            app.StdDevpWEditField = uieditfield(app.RightPanel, 'numeric');
            app.StdDevpWEditField.Editable = 'off';
            app.MaxEditFieldLabel = uilabel(app.RightPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaxEditField.Editable = 'off';
            app.MinEditFieldLabel = uilabel(app.RightPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.RightPanel, 'numeric');
            app.MinEditField.Editable = 'off';
            app.StatisticsLabel = uilabel(app.RightPanel);
            app.StatisticsLabel.Text = 'Statistics';
            app.CurrentValueEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentValueEditFieldLabel.Text = 'Current Value';
            app.CurrentValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.CurrentValueEditField.Editable = 'off';
            app.YLimitsSliderLabel = uilabel(app.RightPanel);
            app.YLimitsSliderLabel.Text = 'Y-Limits';
            app.YLimitsSlider = uislider(app.RightPanel, 'range');
            app.YLimitsSlider.Limits = [0 1000];
            app.YLimitsSlider.ValueChangedFcn = createCallbackFcn(app, @YLimitsSliderValueChanged, true);
            app.YLimitsSlider.Value = [0 5];
            app.MaxPowerEditFieldLabel = uilabel(app.RightPanel);
            app.MaxPowerEditFieldLabel.Text = 'Max Power';
            app.MaxPowerEditField = uieditfield(app.RightPanel, 'numeric');
            app.CurrentetaEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentetaEditFieldLabel.Text = 'Current \eta';
            app.CurrentetaEditField = uieditfield(app.RightPanel, 'numeric');
            app.CurrentetaEditField.Editable = 'off';
            app.TimeElapsedmEditFieldLabel = uilabel(app.RightPanel);
            app.TimeElapsedmEditFieldLabel.Text = 'Time  Elapsed (m)';
            app.TimeElapsedmEditField = uieditfield(app.RightPanel, 'numeric');
            app.GoalEditFieldLabel = uilabel(app.RightPanel);
            app.GoalEditFieldLabel.Text = 'Goal';
            app.GoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.GoalEditField.ValueChangedFcn = createCallbackFcn(app, @GoalEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lightwave_8163a_query

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end